classdef DOT_Simulating_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Tumor_center_random            matlab.ui.control.Switch
        TumorcenterZEditField          matlab.ui.control.NumericEditField
        TumorcenterYEditField          matlab.ui.control.NumericEditField
        TumorcenterXEditField          matlab.ui.control.NumericEditField
        TumorcenterXYZEditFieldLabel   matlab.ui.control.Label
        Cylinder_Height_random         matlab.ui.control.Switch
        TumorShapeDropDown             matlab.ui.control.DropDown
        TumorShapeDropDownLabel        matlab.ui.control.Label
        CylindrHeightcmEditField       matlab.ui.control.NumericEditField
        CylindrHeightcmEditFieldLabel  matlab.ui.control.Label
        tumor_optical_toggle_all       matlab.ui.control.Switch
        ToggleAllSwitch_2Label_2       matlab.ui.control.Label
        RandomLabel_4                  matlab.ui.control.Label
        OpticalParametersTumorLabel    matlab.ui.control.Label
        tumormus1cmEditField           matlab.ui.control.NumericEditField
        mus1cmEditField_2Label         matlab.ui.control.Label
        tumormua1cmEditField           matlab.ui.control.NumericEditField
        mua1cmEditField_2Label         matlab.ui.control.Label
        tumor_mus_random               matlab.ui.control.Switch
        tumor_mua_random               matlab.ui.control.Switch
        params_toggle_all              matlab.ui.control.Switch
        ToggleAllSwitchLabel_2         matlab.ui.control.Label
        StretchMeshLabel               matlab.ui.control.Label
        QMNumbersqrtNEditField         matlab.ui.control.NumericEditField
        QMNumbersqrtNEditFieldLabel    matlab.ui.control.Label
        TumorRadiuscmEditField         matlab.ui.control.NumericEditField
        TumorRadiuscmEditFieldLabel    matlab.ui.control.Label
        RandomLabel_3                  matlab.ui.control.Label
        QM_number_random               matlab.ui.control.Switch
        tumor_radius_random            matlab.ui.control.Switch
        SimulationParametersLabel      matlab.ui.control.Label
        QuantityEditField              matlab.ui.control.NumericEditField
        QuantityEditFieldLabel         matlab.ui.control.Label
        optical_toggle_all             matlab.ui.control.Switch
        ToggleAllSwitch_2Label         matlab.ui.control.Label
        stretch_toggle_all             matlab.ui.control.Switch
        ToggleAllSwitchLabel           matlab.ui.control.Label
        ModeSwitch                     matlab.ui.control.Switch
        ModeSwitchLabel                matlab.ui.control.Label
        ScaleZEditField                matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel           matlab.ui.control.Label
        ScaleYEditField                matlab.ui.control.NumericEditField
        ScaleYEditFieldLabel           matlab.ui.control.Label
        ScaleXEditField                matlab.ui.control.NumericEditField
        ScaleXEditFieldLabel           matlab.ui.control.Label
        refEditField                   matlab.ui.control.NumericEditField
        refEditFieldLabel              matlab.ui.control.Label
        mus1cmEditField                matlab.ui.control.NumericEditField
        mus1cmEditFieldLabel           matlab.ui.control.Label
        mua1cmEditField                matlab.ui.control.NumericEditField
        mua1cmEditFieldLabel           matlab.ui.control.Label
        RandomLabel_2                  matlab.ui.control.Label
        ScaleZ_random                  matlab.ui.control.Switch
        ScaleY_random                  matlab.ui.control.Switch
        ScaleX_random                  matlab.ui.control.Switch
        ref_random                     matlab.ui.control.Switch
        mus_random                     matlab.ui.control.Switch
        RandomLabel                    matlab.ui.control.Label
        mua_random                     matlab.ui.control.Switch
        OpticalParametersBreastLabel   matlab.ui.control.Label
        OrenSchorRonMilutinLabel       matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        DOTSimulatingToolLabel         matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function Check_random_toggles(app,source)
            if source == 1
                if strcmp(app.ScaleX_random.Value,'On') && strcmp(app.ScaleY_random.Value,'On') && strcmp(app.ScaleZ_random.Value,'On')
                    app.stretch_toggle_all.Value = 'On';
                else
                    app.stretch_toggle_all.Value = 'Off';
                end
            elseif source == 2
                if strcmp(app.tumor_radius_random.Value,'On') && strcmp(app.QM_number_random.Value,'On')
                    app.params_toggle_all.Value = 'On';
                else
                    app.params_toggle_all.Value = 'Off';
                end
            elseif source == 3
                if strcmp(app.tumor_mua_random.Value,'On') && strcmp(app.tumor_mus_random.Value,'On')
                    app.tumor_optical_toggle_all.Value = 'On';
                else
                    app.tumor_optical_toggle_all.Value = 'Off';
                end
            else
                if strcmp(app.mua_random.Value,'On') && strcmp(app.mus_random.Value,'On') && strcmp(app.ref_random.Value,'On')
                    app.optical_toggle_all.Value = 'On';
                else
                    app.optical_toggle_all.Value = 'Off';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            fullpath = mfilename('fullpath');
            fullpath = fileparts(fullpath);
            simdir = fullfile(fullpath,'Simulations');
            current_datetime = now;
            formattedDateTime = datestr(current_datetime, 'yyyy-mm-dd_HH-MM-SS');
            formattedDateTime = strrep(formattedDateTime, '-', '_');
            simdir = fullfile(simdir,formattedDateTime);
            mkdir(simdir);
            
            [file,path] = uigetfile('*.msh','Select the base msh file');
    
            if isequal(path, 0) || isequal(file, 0)
                disp('User canceled file selection');
            else
                fullpath = fullfile(path,file); 


                if app.QuantityEditField.Value > 1
                    disp("Running multiple simulations, figures will not be displayed.")
                    multiple_flag = 1;
                else
                    multiple_flag = 0;
                end
    
                % Create progress dialog
                progressDialog = uiprogressdlg(app.UIFigure, 'Title', 'Please Wait', ...
                    'Message', 'Simulation in progress...', 'Indeterminate', 'off');

                for i=1:app.QuantityEditField.Value   
                    iter_folder = fullfile(simdir,num2str(i));
                    mkdir(iter_folder);                                                                              
                    mesh_path = fullfile(iter_folder,'Simulation_Mesh.msh');
                    mat_path = fullfile(iter_folder,'Simulation_Mesh.mat');

                    % Set values
                    if strcmp(app.ScaleX_random.Value,'On')
                        scalex = 5*rand();
                    else
                        scalex = app.ScaleXEditField.Value;
                    end

                    if strcmp(app.ScaleY_random.Value,'On')
                        scaley = 5*rand();
                    else
                        scaley = app.ScaleYEditField.Value;
                    end

                    if strcmp(app.ScaleZ_random.Value,'On')
                        scalez = 5*rand();
                    else
                        scalez = app.ScaleZEditField.Value;
                    end

                    if strcmp(app.mua_random.Value,'On')
                        mua = 0.03 + 0.04*rand();
                    else
                        mua = app.mua1cmEditField.Value;
                    end

                    if strcmp(app.mus_random.Value,'On')
                        mus = 10 + 10*rand();
                    else
                        mus = app.mus1cmEditField.Value;
                    end

                    if strcmp(app.ref_random.Value,'On')
                        ref = 1.5 + 0.05*rand();
                    else
                        ref = app.refEditField.Value;
                    end

                    if strcmp(app.tumor_radius_random.Value,'On')
                        rad = 2 + 6*rand();
                    else
                        rad = app.TumorRadiuscmEditField.Value;
                    end

                    if strcmp(app.Cylinder_Height_random.Value,'On')
                        height = rand();
                    else
                        height = app.CylindrHeightcmEditField.Value;
                    end

                    center_rand_flag = strcmp(app.Tumor_center_random.Value,'On');

                    if center_rand_flag
                        center = [rand() rand() rand()];
                    else
                        center = [app.TumorcenterXEditField.Value app.TumorcenterYEditField.Value app.TumorcenterZEditField.Value];
                    end

                    if strcmp(app.QM_number_random.Value,'On')
                        nqm = randi([8, 16]);
                    else
                        nqm = app.QMNumbersqrtNEditField.Value;
                    end

                    if strcmp(app.tumor_mua_random.Value,'On')
                        tumor_mua = 0.01 + 0.03*rand();
                    else
                        tumor_mua = app.tumormua1cmEditField.Value;
                    end

                    if strcmp(app.tumor_mus_random.Value,'On')
                        tumor_mus = 20 + 20*rand();
                    else
                        tumor_mus = app.tumormus1cmEditField.Value;
                    end

                    if scalex ~= 1 || scaley ~= 1 || scalez ~= 1
                        stretch_mesh(fullpath,mesh_path,scalex,scaley,scalez);                   
                    else                    
                        copyfile(fullpath,mesh_path); 
                    end

                    shapeType = app.TumorShapeDropDown.Value;

                    conv_msh_to_nodes_elements_arrays(mesh_path, mat_path)
                    simulation_3d(mat_path,mua,mus,ref,tumor_mua,tumor_mus,rad,height,shapeType,nqm,multiple_flag,center,center_rand_flag);

                     % Update progress dialog
                    progressDialog.Value = i / app.QuantityEditField.Value;
                    progressDialog.Message = sprintf('Progress: %d%%', round(i / app.QuantityEditField.Value * 100));
                end
        

                % Close progress dialog  
                close(progressDialog);
                disp("Finished.")
            end
                        
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            if strcmp(value,'Multi')
                app.QuantityEditField.Enable = "on";
            else
                app.QuantityEditField.Value = 1;
                app.QuantityEditField.Enable = "off";
            end
        end

        % Value changed function: stretch_toggle_all
        function stretch_toggle_allValueChanged(app, event)
            value = app.stretch_toggle_all.Value;
            if strcmp(value,'On')
                app.ScaleX_random.Value = 'On';
                app.ScaleY_random.Value = 'On';
                app.ScaleZ_random.Value = 'On';
                app.ScaleXEditField.Enable = "off";
                app.ScaleYEditField.Enable = "off";
                app.ScaleZEditField.Enable = "off";                
            else
                app.ScaleX_random.Value = 'Off';
                app.ScaleY_random.Value = 'Off';
                app.ScaleZ_random.Value = 'Off';
                app.ScaleXEditField.Enable = "on";
                app.ScaleYEditField.Enable = "on";
                app.ScaleZEditField.Enable = "on"; 
            end
        end

        % Value changed function: optical_toggle_all
        function optical_toggle_allValueChanged(app, event)
            value = app.optical_toggle_all.Value;
            if strcmp(value,'On')
                app.mua_random.Value = 'On';
                app.mus_random.Value = 'On';
                app.ref_random.Value = 'On';
                app.mua1cmEditField.Enable = "off";
                app.mus1cmEditField.Enable = "off";
                app.refEditField.Enable = "off";                             
            else
                app.mua_random.Value = 'Off';
                app.mus_random.Value = 'Off';
                app.ref_random.Value = 'Off';
                app.mua1cmEditField.Enable = "on";
                app.mus1cmEditField.Enable = "on";
                app.refEditField.Enable = "on";   
            end
        end

        % Value changed function: mua_random
        function mua_randomValueChanged(app, event)
            value = app.mua_random.Value;
            app.Check_random_toggles(0);
            if strcmp(value,'On')
                app.mua1cmEditField.Enable = "off";
            else
                app.mua1cmEditField.Enable = "on";
            end
        end

        % Value changed function: mus_random
        function mus_randomValueChanged(app, event)
            value = app.mus_random.Value;
            app.Check_random_toggles(0);
            if strcmp(value,'On')
                app.mus1cmEditField.Enable = "off";
            else
                app.mus1cmEditField.Enable = "on";
            end
        end

        % Value changed function: ref_random
        function ref_randomValueChanged(app, event)
            value = app.ref_random.Value;
            app.Check_random_toggles(0);
            if strcmp(value,'On')
                app.refEditField.Enable = "off";
            else
                app.refEditField.Enable = "on";
            end
        end

        % Value changed function: ScaleX_random
        function ScaleX_randomValueChanged(app, event)
            value = app.ScaleX_random.Value;
            app.Check_random_toggles(1);
            if strcmp(value,'On')
                app.ScaleXEditField.Enable = "off";
            else
                app.ScaleXEditField.Enable = "on";
            end
        end

        % Value changed function: ScaleY_random
        function ScaleY_randomValueChanged(app, event)
            value = app.ScaleY_random.Value;
            app.Check_random_toggles(1);
            if strcmp(value,'On')
                app.ScaleYEditField.Enable = "off";
            else
                app.ScaleYEditField.Enable = "on";
            end
        end

        % Value changed function: ScaleZ_random
        function ScaleZ_randomValueChanged(app, event)
            value = app.ScaleZ_random.Value;
            app.Check_random_toggles(1);
            if strcmp(value,'On')
                app.ScaleZEditField.Enable = "off";
            else
                app.ScaleZEditField.Enable = "on";
            end
        end

        % Value changed function: tumor_radius_random
        function tumor_radius_randomValueChanged(app, event)
            value = app.tumor_radius_random.Value;
            app.Check_random_toggles(2);
            if strcmp(value,'On')
                app.TumorRadiuscmEditField.Enable = "off";
            else
                app.TumorRadiuscmEditField.Enable = "on";
            end
        end

        % Value changed function: QM_number_random
        function QM_number_randomValueChanged(app, event)
            value = app.QM_number_random.Value;
            app.Check_random_toggles(2);
            if strcmp(value,'On')
                app.QMNumbersqrtNEditField.Enable = "off";
            else
                app.QMNumbersqrtNEditField.Enable = "on";
            end
        end

        % Value changed function: params_toggle_all
        function params_toggle_allValueChanged(app, event)
            value = app.params_toggle_all.Value;
            if strcmp(value,'On')
                app.QM_number_random.Value = 'On';
                app.tumor_radius_random.Value = 'On';
                app.QMNumbersqrtNEditField.Enable = "off";
                app.TumorRadiuscmEditField.Enable = "off";
            else
                app.QM_number_random.Value = 'Off';
                app.tumor_radius_random.Value = 'Off';
                app.QMNumbersqrtNEditField.Enable = "on";
                app.TumorRadiuscmEditField.Enable = "on";
            end
        end

        % Value changed function: tumor_mua_random
        function tumor_mua_randomValueChanged(app, event)
            value = app.tumor_mua_random.Value;
            app.Check_random_toggles(3);
            if strcmp(value,'On')
                app.tumormua1cmEditField.Enable = "off";
            else
                app.tumormua1cmEditField.Enable = "on";
            end
        end

        % Value changed function: tumor_mus_random
        function tumor_mus_randomValueChanged(app, event)
            value = app.tumor_mus_random.Value;
            app.Check_random_toggles(3);
            if strcmp(value,'On')
                app.tumormus1cmEditField.Enable = "off";
            else
                app.tumormus1cmEditField.Enable = "on";
            end
        end

        % Value changed function: tumor_optical_toggle_all
        function tumor_optical_toggle_allValueChanged(app, event)
            value = app.tumor_optical_toggle_all.Value;
            if strcmp(value,'On')
                app.tumor_mua_random.Value = 'On';
                app.tumor_mus_random.Value = 'On';
                app.tumormua1cmEditField.Enable = "off";
                app.tumormus1cmEditField.Enable = "off";
            else
                app.tumor_mua_random.Value = 'Off';
                app.tumor_mus_random.Value = 'Off';
                app.tumormua1cmEditField.Enable = "on";
                app.tumormus1cmEditField.Enable = "on";  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DOTSimulatingToolLabel = uilabel(app.UIFigure);
            app.DOTSimulatingToolLabel.Text = 'DOT Simulating Tool';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.OrenSchorRonMilutinLabel = uilabel(app.UIFigure);
            app.OrenSchorRonMilutinLabel.Text = '© Oren Schor & Ron Milutin';
            app.OpticalParametersBreastLabel = uilabel(app.UIFigure);
            app.OpticalParametersBreastLabel.Text = 'Optical Parameters (Breast)';
            app.mua_random = uiswitch(app.UIFigure, 'slider');
            app.mua_random.ValueChangedFcn = createCallbackFcn(app, @mua_randomValueChanged, true);
            app.RandomLabel = uilabel(app.UIFigure);
            app.RandomLabel.Text = 'Random';
            app.mus_random = uiswitch(app.UIFigure, 'slider');
            app.mus_random.ValueChangedFcn = createCallbackFcn(app, @mus_randomValueChanged, true);
            app.ref_random = uiswitch(app.UIFigure, 'slider');
            app.ref_random.ValueChangedFcn = createCallbackFcn(app, @ref_randomValueChanged, true);
            app.ScaleX_random = uiswitch(app.UIFigure, 'slider');
            app.ScaleX_random.ValueChangedFcn = createCallbackFcn(app, @ScaleX_randomValueChanged, true);
            app.ScaleY_random = uiswitch(app.UIFigure, 'slider');
            app.ScaleY_random.ValueChangedFcn = createCallbackFcn(app, @ScaleY_randomValueChanged, true);
            app.ScaleZ_random = uiswitch(app.UIFigure, 'slider');
            app.ScaleZ_random.ValueChangedFcn = createCallbackFcn(app, @ScaleZ_randomValueChanged, true);
            app.RandomLabel_2 = uilabel(app.UIFigure);
            app.RandomLabel_2.Text = 'Random';
            app.mua1cmEditFieldLabel = uilabel(app.UIFigure);
            app.mua1cmEditFieldLabel.Text = 'mua [1/cm]';
            app.mua1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mua1cmEditField.Limits = [0.03 0.07];
            app.mua1cmEditField.Value = 0.03;
            app.mus1cmEditFieldLabel = uilabel(app.UIFigure);
            app.mus1cmEditFieldLabel.Text = 'mus [1/cm]';
            app.mus1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.mus1cmEditField.Limits = [10 20];
            app.mus1cmEditField.Value = 10;
            app.refEditFieldLabel = uilabel(app.UIFigure);
            app.refEditFieldLabel.Text = 'ref';
            app.refEditField = uieditfield(app.UIFigure, 'numeric');
            app.refEditField.Limits = [1.5 1.55];
            app.refEditField.Value = 1.5;
            app.ScaleXEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleXEditFieldLabel.Text = 'Scale X';
            app.ScaleXEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleXEditField.Value = 1;
            app.ScaleYEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleYEditFieldLabel.Text = 'Scale Y';
            app.ScaleYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleYEditField.Value = 1;
            app.ScaleZEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleZEditField.Value = 1;
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'Single', 'Multi'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Single';
            app.ToggleAllSwitchLabel = uilabel(app.UIFigure);
            app.ToggleAllSwitchLabel.Text = 'Toggle All';
            app.stretch_toggle_all = uiswitch(app.UIFigure, 'slider');
            app.stretch_toggle_all.ValueChangedFcn = createCallbackFcn(app, @stretch_toggle_allValueChanged, true);
            app.ToggleAllSwitch_2Label = uilabel(app.UIFigure);
            app.ToggleAllSwitch_2Label.Text = 'Toggle All';
            app.optical_toggle_all = uiswitch(app.UIFigure, 'slider');
            app.optical_toggle_all.ValueChangedFcn = createCallbackFcn(app, @optical_toggle_allValueChanged, true);
            app.QuantityEditFieldLabel = uilabel(app.UIFigure);
            app.QuantityEditFieldLabel.Text = 'Quantity';
            app.QuantityEditField = uieditfield(app.UIFigure, 'numeric');
            app.QuantityEditField.Limits = [1 Inf];
            app.QuantityEditField.Enable = 'off';
            app.QuantityEditField.Value = 1;
            app.SimulationParametersLabel = uilabel(app.UIFigure);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.tumor_radius_random = uiswitch(app.UIFigure, 'slider');
            app.tumor_radius_random.ValueChangedFcn = createCallbackFcn(app, @tumor_radius_randomValueChanged, true);
            app.QM_number_random = uiswitch(app.UIFigure, 'slider');
            app.QM_number_random.ValueChangedFcn = createCallbackFcn(app, @QM_number_randomValueChanged, true);
            app.RandomLabel_3 = uilabel(app.UIFigure);
            app.RandomLabel_3.Text = 'Random';
            app.TumorRadiuscmEditFieldLabel = uilabel(app.UIFigure);
            app.TumorRadiuscmEditFieldLabel.Text = 'Tumor Radius [cm]';
            app.TumorRadiuscmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TumorRadiuscmEditField.Limits = [0 8];
            app.TumorRadiuscmEditField.Value = 3;
            app.QMNumbersqrtNEditFieldLabel = uilabel(app.UIFigure);
            app.QMNumbersqrtNEditFieldLabel.Text = 'QM Number [sqrt(N)]';
            app.QMNumbersqrtNEditField = uieditfield(app.UIFigure, 'numeric');
            app.QMNumbersqrtNEditField.Limits = [1 Inf];
            app.QMNumbersqrtNEditField.Value = 12;
            app.StretchMeshLabel = uilabel(app.UIFigure);
            app.StretchMeshLabel.Text = 'Stretch Mesh';
            app.ToggleAllSwitchLabel_2 = uilabel(app.UIFigure);
            app.ToggleAllSwitchLabel_2.Text = 'Toggle All';
            app.params_toggle_all = uiswitch(app.UIFigure, 'slider');
            app.params_toggle_all.ValueChangedFcn = createCallbackFcn(app, @params_toggle_allValueChanged, true);
            app.tumor_mua_random = uiswitch(app.UIFigure, 'slider');
            app.tumor_mua_random.ValueChangedFcn = createCallbackFcn(app, @tumor_mua_randomValueChanged, true);
            app.tumor_mus_random = uiswitch(app.UIFigure, 'slider');
            app.tumor_mus_random.ValueChangedFcn = createCallbackFcn(app, @tumor_mus_randomValueChanged, true);
            app.mua1cmEditField_2Label = uilabel(app.UIFigure);
            app.mua1cmEditField_2Label.Text = 'mua [1/cm]';
            app.tumormua1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.tumormua1cmEditField.Limits = [0.01 0.04];
            app.tumormua1cmEditField.Value = 0.02;
            app.mus1cmEditField_2Label = uilabel(app.UIFigure);
            app.mus1cmEditField_2Label.Text = 'mus [1/cm]';
            app.tumormus1cmEditField = uieditfield(app.UIFigure, 'numeric');
            app.tumormus1cmEditField.Limits = [20 40];
            app.tumormus1cmEditField.Value = 30;
            app.OpticalParametersTumorLabel = uilabel(app.UIFigure);
            app.OpticalParametersTumorLabel.Text = 'Optical Parameters (Tumor)';
            app.RandomLabel_4 = uilabel(app.UIFigure);
            app.RandomLabel_4.Text = 'Random';
            app.ToggleAllSwitch_2Label_2 = uilabel(app.UIFigure);
            app.ToggleAllSwitch_2Label_2.Text = 'Toggle All';
            app.tumor_optical_toggle_all = uiswitch(app.UIFigure, 'slider');
            app.tumor_optical_toggle_all.ValueChangedFcn = createCallbackFcn(app, @tumor_optical_toggle_allValueChanged, true);
            app.CylindrHeightcmEditFieldLabel = uilabel(app.UIFigure);
            app.CylindrHeightcmEditFieldLabel.Text = 'Cylindr Height [cm]';
            app.CylindrHeightcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TumorShapeDropDownLabel = uilabel(app.UIFigure);
            app.TumorShapeDropDownLabel.Text = 'Tumor Shape';
            app.TumorShapeDropDown = uidropdown(app.UIFigure);
            app.TumorShapeDropDown.Items = {'Sphere', 'Cylinder'};
            app.TumorShapeDropDown.Value = 'Sphere';
            app.Cylinder_Height_random = uiswitch(app.UIFigure, 'slider');
            app.TumorcenterXYZEditFieldLabel = uilabel(app.UIFigure);
            app.TumorcenterXYZEditFieldLabel.Text = 'Tumor center [X, Y, Z]';
            app.TumorcenterXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TumorcenterYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TumorcenterZEditField = uieditfield(app.UIFigure, 'numeric');
            app.Tumor_center_random = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOT_Simulating_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end